classdef WorkingAnalysisApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Image2                        matlab.ui.control.Image
        Image                         matlab.ui.control.Image
        Permittedheightrange12mLabel  matlab.ui.control.Label
        Permittedweightrange3091kgLabel  matlab.ui.control.Label
        ProsthesisSizeandDimensionsLabel  matlab.ui.control.Label
        ResultsTable                  matlab.ui.control.Table
        GenerateButton                matlab.ui.control.Button
        DKneeProsthesisSizeGeneratorLabel  matlab.ui.control.Label
        PatientbodyheightmEditField   matlab.ui.control.NumericEditField
        PatientbodyheightmEditFieldLabel  matlab.ui.control.Label
        PatientbodyweightkgEditField  matlab.ui.control.NumericEditField
        PatientbodyweightkgEditFieldLabel  matlab.ui.control.Label
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            BW = app.PatientbodyweightkgEditField.Value;
            H = app.PatientbodyheightmEditField.Value;
            rootDir = fileparts(fileparts(which('WorkingAnalysisApp')));
            addpath(rootDir);
            addpath(fullfile(rootDir, 'analysis'));
            addpath(fullfile(rootDir, 'components'));

    
    % ---- Run analysis ----
            data = Main.getResults(BW,H);
        app.ResultsTable.Data = table2cell(data.table); 
 

    % ---- Wrap app in two-tab layout (first run only) ----
    tg = findobj(app.UIFigure, 'Tag', 'AppTabGroup');
    if isempty(tg)
        figW = app.UIFigure.Position(3);
        figH = app.UIFigure.Position(4);
        tg = uitabgroup(app.UIFigure, 'Position', [0 0 figW figH], ...
                        'Tag', 'AppTabGroup');
        uitab(tg, 'Title', 'Calculated Dimensions', 'Tag', 'TabResults');
        uitab(tg, 'Title', 'Gait Cycle Plots',      'Tag', 'TabPlots');
        uitab(tg, 'Title', 'Log File',      'Tag', 'TabLog');
        tabR = findobj(tg, 'Tag', 'TabResults');
        kids = app.UIFigure.Children;
        for k = 1:numel(kids)
            if ~isequal(kids(k), tg)
                kids(k).Parent = tabR;
            end
        end
    end

    % ---- Build scrollable plots tab ----
    tabP = findobj(tg, 'Tag', 'TabPlots');
    delete(tabP.Children);

    plotH  = 400;
    plotW  = tg.Position(3) - 80;
    gap    = 20;
    totalH = 6*(plotH + gap) + gap;
    vpH    = tg.Position(4) - 32;

    sp = uipanel(tabP, 'Position', [0 0 tg.Position(3) vpH], ...
                 'Scrollable', 'on', 'BorderType', 'none');

    axArray = gobjects(1, 6);
    for i = 1:6
        yPos       = totalH - i*(plotH + gap) + gap;
        axArray(i) = uiaxes(sp, 'Units', 'pixels', ...
                            'Position', [40 yPos plotW plotH]);
    end

    GaitPlots.plotAllInAxes(data.gaitSeries, data.pressureSeries, axArray);

    % ---- Build log tab ----
    tabL = findobj(tg, 'Tag', 'TabLog');
    delete(tabL.Children);
    
    logArea = uitextarea(tabL, ...
        'Editable','off', ...
        'Position',[20 20 tg.Position(3)-40 tg.Position(4)-40]);

    basePath = fileparts(mfilename('fullpath'));
    logDir = fullfile(basePath,'..', '..', 'Log');
        if ~isfolder(logDir)
            mkdir(logDir);
        end
    logFile = fullfile(logDir, 'group12_LOG.txt');

    if isfile(logFile)
        txt = fileread(logFile);
        logArea.Value = splitlines(txt);
    else
        logArea.Value = "Log file not found.";
    end
       
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PatientbodyweightkgEditFieldLabel = uilabel(app.UIFigure);
            app.PatientbodyweightkgEditFieldLabel.Text = 'Patient body weight (kg)';
            app.PatientbodyweightkgEditField = uieditfield(app.UIFigure, 'numeric');
            app.PatientbodyweightkgEditField.Limits = [30 91];
            app.PatientbodyweightkgEditField.Value = 30;
            app.PatientbodyheightmEditFieldLabel = uilabel(app.UIFigure);
            app.PatientbodyheightmEditFieldLabel.Text = 'Patient body height (m)';
            app.PatientbodyheightmEditField = uieditfield(app.UIFigure, 'numeric');
            app.PatientbodyheightmEditField.Limits = [1 2];
            app.PatientbodyheightmEditField.Value = 1;
            app.DKneeProsthesisSizeGeneratorLabel = uilabel(app.UIFigure);
            app.DKneeProsthesisSizeGeneratorLabel.Text = 'D''Knee Prosthesis Size Generator';
            app.GenerateButton = uibutton(app.UIFigure, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.ResultsTable = uitable(app.UIFigure);
            app.ResultsTable.ColumnName = {'Variable name'; 'Description'; 'Value'; 'Units'};
            app.ResultsTable.RowName = {};
            app.ProsthesisSizeandDimensionsLabel = uilabel(app.UIFigure);
            app.ProsthesisSizeandDimensionsLabel.Text = 'Prosthesis Size and Dimensions';
            app.Permittedweightrange3091kgLabel = uilabel(app.UIFigure);
            app.Permittedweightrange3091kgLabel.Text = '(Permitted weight range: 30-91 kg)';
            app.Permittedheightrange12mLabel = uilabel(app.UIFigure);
            app.Permittedheightrange12mLabel.Text = '(Permitted height range: 1-2 m)';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'isometric_assembly.png');
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'knee_logo.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WorkingAnalysisApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end